classdef interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LPCVOCODERUIFigure             matlab.ui.Figure
        AxesInput                      matlab.ui.control.UIAxes
        InputParametersPanel           matlab.ui.container.Panel
        ExcitationSignalDropDownLabel  matlab.ui.control.Label
        ExcitationSignalDropDown       matlab.ui.control.DropDown
        FrameLengthmsEditFieldLabel    matlab.ui.control.Label
        FrameLengthEditField           matlab.ui.control.NumericEditField
        WindowDropDownLabel            matlab.ui.control.Label
        WindowDropDown                 matlab.ui.control.DropDown
        OverlapEditFieldLabel          matlab.ui.control.Label
        OverlapEditField               matlab.ui.control.NumericEditField
        RUNButton                      matlab.ui.control.Button
        FsEditFieldLabel               matlab.ui.control.Label
        FsEditField                    matlab.ui.control.NumericEditField
        LPCOrderEditFieldLabel         matlab.ui.control.Label
        LPCOrderEditField              matlab.ui.control.NumericEditField
        InputSelectionButtonGroup      matlab.ui.container.ButtonGroup
        SamplesButton                  matlab.ui.control.RadioButton
        RecordButton                   matlab.ui.control.RadioButton
        InputDropDownLabel             matlab.ui.control.Label
        InputDropDown                  matlab.ui.control.DropDown
        ResidualsFileDropDownLabel     matlab.ui.control.Label
        ResidualsFileDropDown          matlab.ui.control.DropDown
        PlayResiduals                  matlab.ui.control.Button
        PlayInput                      matlab.ui.control.Button
        MIDIDeviceLabel                matlab.ui.control.Label
        MIDIDropDown                   matlab.ui.control.DropDown
        AxesOutput                     matlab.ui.control.UIAxes
        AxesState                      matlab.ui.control.UIAxes
        AxesZCR                        matlab.ui.control.UIAxes
        PlayOutput                     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if app.ExcitationSignalDropDown.Value=='MIDI'
                    devices=mididevinfo;
                    [a,b]=size(devices.output);
                    for i=1:b
                        new_Item=devices.output(i).Name;
                        app.MIDIDropDown.Items=[app.MIDIDropDown.Items new_Item];
                    end
                end
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            start_vocoder(app);
        end

        % Button pushed function: PlayOutput
        function PlayOutputButtonPushed(app, event)
            [y,fs]=audioread("output.wav");
            soundsc(y,fs);
        end

        % Button pushed function: PlayInput
        function PlayInputButtonPushed(app, event)
            [x,fs]=audioread("AudioSamples\"+app.InputDropDown.Value);
            soundsc(x,fs);
        end

        % Selection changed function: InputSelectionButtonGroup
        function InputSelectionButtonGroupSelectionChanged(app, event)
            if app.RecordButton.Value==1
                app.InputDropDown.Enable=0;
                app.PlayInput.Enable=0;
            else
                app.InputDropDown.Enable=1;
                app.PlayInput.Enable=1;
            end
        end

        % Value changed function: ExcitationSignalDropDown
        function ExcitationSignalDropDownValueChanged(app, event)
            if app.ExcitationSignalDropDown.Value=="MIDI" || app.ExcitationSignalDropDown.Value=="Music File"
                app.ResidualsFileDropDown.Enable=0;
                app.PlayResiduals.Enable=0;
            else
                app.ResidualsFileDropDown.Enable=1; 
                app.PlayResiduals.Enable=1;
            end
            if app.ExcitationSignalDropDown.Value=="MIDI"
                app.MIDIDropDown.Enable=1;
            else
                app.MIDIDropDown.Enable=0;
            end
        end

        % Button pushed function: PlayResiduals
        function PlayResidualsPushed(app, event)
            [file,fs]=audioread("AudioSamples\"+app.ResidualsFileDropDown.Value);
            soundsc(file,fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LPCVOCODERUIFigure = uifigure('Visible', 'off');
            app.LPCVOCODERUIFigure.Name = 'LPC VOCODER';
            app.AxesInput = uiaxes(app.LPCVOCODERUIFigure);
            title(app.AxesInput, 'Input Signal')
            xlabel(app.AxesInput, 'X')
            ylabel(app.AxesInput, 'Y')
            app.InputParametersPanel = uipanel(app.LPCVOCODERUIFigure);
            app.InputParametersPanel.Title = 'Input Parameters';
            app.ExcitationSignalDropDownLabel = uilabel(app.InputParametersPanel);
            app.ExcitationSignalDropDownLabel.Text = 'Excitation Signal';
            app.ExcitationSignalDropDown = uidropdown(app.InputParametersPanel);
            app.ExcitationSignalDropDown.Items = {'MIDI', 'Music File', 'Residuals'};
            app.ExcitationSignalDropDown.ValueChangedFcn = createCallbackFcn(app, @ExcitationSignalDropDownValueChanged, true);
            app.ExcitationSignalDropDown.Value = 'MIDI';
            app.FrameLengthmsEditFieldLabel = uilabel(app.InputParametersPanel);
            app.FrameLengthmsEditFieldLabel.Text = 'Frame Length [ms]';
            app.FrameLengthEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.FrameLengthEditField.Limits = [5 50];
            app.FrameLengthEditField.Value = 20;
            app.WindowDropDownLabel = uilabel(app.InputParametersPanel);
            app.WindowDropDownLabel.Text = 'Window';
            app.WindowDropDown = uidropdown(app.InputParametersPanel);
            app.WindowDropDown.Items = {'Rectangular', 'Bartlett', 'Hanning', 'Hamming', 'Blackman-Harris', ''};
            app.WindowDropDown.Value = 'Rectangular';
            app.OverlapEditFieldLabel = uilabel(app.InputParametersPanel);
            app.OverlapEditFieldLabel.Text = 'Overlap [%]';
            app.OverlapEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.OverlapEditField.Limits = [0 100];
            app.RUNButton = uibutton(app.InputParametersPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.FsEditFieldLabel = uilabel(app.InputParametersPanel);
            app.FsEditFieldLabel.Enable = 'off';
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.FsEditField.Limits = [0 Inf];
            app.FsEditField.Enable = 'off';
            app.LPCOrderEditFieldLabel = uilabel(app.InputParametersPanel);
            app.LPCOrderEditFieldLabel.Enable = 'off';
            app.LPCOrderEditFieldLabel.Text = 'LPC Order';
            app.LPCOrderEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.LPCOrderEditField.Limits = [1 100];
            app.LPCOrderEditField.Enable = 'off';
            app.LPCOrderEditField.Value = 1;
            app.InputSelectionButtonGroup = uibuttongroup(app.InputParametersPanel);
            app.InputSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InputSelectionButtonGroupSelectionChanged, true);
            app.InputSelectionButtonGroup.Title = 'InputSelection';
            app.SamplesButton = uiradiobutton(app.InputSelectionButtonGroup);
            app.SamplesButton.Text = 'Samples';
            app.SamplesButton.Value = true;
            app.RecordButton = uiradiobutton(app.InputSelectionButtonGroup);
            app.RecordButton.Text = 'Record';
            app.InputDropDownLabel = uilabel(app.InputParametersPanel);
            app.InputDropDownLabel.Text = 'Input';
            app.InputDropDown = uidropdown(app.InputParametersPanel);
            app.InputDropDown.Items = {'prova_vocoder_8.wav', 'prova_vocoder_16.wav', 'prova_vocoder_44.wav', 'prova_vocoder_44_2.wav', 's2.wav', 's6.wav'};
            app.InputDropDown.Value = 'prova_vocoder_8.wav';
            app.ResidualsFileDropDownLabel = uilabel(app.InputParametersPanel);
            app.ResidualsFileDropDownLabel.Text = 'Residuals File';
            app.ResidualsFileDropDown = uidropdown(app.InputParametersPanel);
            app.ResidualsFileDropDown.Items = {'074_acoustic-guitar-strummy1.wav', 'flute.wav', 'mistery-piano-violin-sad-melody.wav', 'wet-guitar-trap-melody_170bpm.wav', 'dubstep.flac', 'dubstep_beats.mp3', 'flamenco.flac', 'flamenco_melody.mp3', 'hiphop.mp3', 'hiphop_onsets_complex_stereo.mp3', 'hiphop_onsets_hfc_stereo.mp3', 'mozart_c_major_30sec.flac', 'mozart_c_major_30sec_melody.mp3', 'techno_loop.flac', 'Vivaldi_Sonata_5_II_Allegro.flac'};
            app.ResidualsFileDropDown.Enable = 'off';
            app.ResidualsFileDropDown.Value = '074_acoustic-guitar-strummy1.wav';
            app.PlayResiduals = uibutton(app.InputParametersPanel, 'push');
            app.PlayResiduals.ButtonPushedFcn = createCallbackFcn(app, @PlayResidualsPushed, true);
            app.PlayResiduals.Enable = 'off';
            app.PlayResiduals.Text = 'Play';
            app.PlayInput = uibutton(app.InputParametersPanel, 'push');
            app.PlayInput.ButtonPushedFcn = createCallbackFcn(app, @PlayInputButtonPushed, true);
            app.PlayInput.Text = {'Play'; ''};
            app.MIDIDeviceLabel = uilabel(app.InputParametersPanel);
            app.MIDIDeviceLabel.Text = 'MIDI Device';
            app.MIDIDropDown = uidropdown(app.InputParametersPanel);
            app.MIDIDropDown.Items = {};
            app.MIDIDropDown.Value = {};
            app.AxesOutput = uiaxes(app.LPCVOCODERUIFigure);
            title(app.AxesOutput, 'OutputSignal')
            xlabel(app.AxesOutput, 'X')
            ylabel(app.AxesOutput, 'Y')
            app.AxesState = uiaxes(app.LPCVOCODERUIFigure);
            title(app.AxesState, 'State')
            xlabel(app.AxesState, 'X')
            ylabel(app.AxesState, 'Y')
            app.AxesZCR = uiaxes(app.LPCVOCODERUIFigure);
            title(app.AxesZCR, 'ZCR')
            xlabel(app.AxesZCR, 'X')
            ylabel(app.AxesZCR, 'Y')
            app.PlayOutput = uibutton(app.LPCVOCODERUIFigure, 'push');
            app.PlayOutput.ButtonPushedFcn = createCallbackFcn(app, @PlayOutputButtonPushed, true);
            app.PlayOutput.Text = {'Play Output'; ''};
            % Show the figure after all components are created
            app.LPCVOCODERUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LPCVOCODERUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LPCVOCODERUIFigure)
        end
    end
end